classdef Analyze_smLD_Handy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TrajectorymatrixEditFieldLabel  matlab.ui.control.Label
        TrajectorymatrixEditField       matlab.ui.control.EditField
        LoadTrajectoriesButton          matlab.ui.control.Button
        DiscardTrajectoryButton         matlab.ui.control.Button
        SaveandProceedButton            matlab.ui.control.Button
        LocationSliderLabel             matlab.ui.control.Label
        LocationSlider                  matlab.ui.control.Slider
        SelectedChangePointDropDownLabel  matlab.ui.control.Label
        SelectedChangePointDropDown     matlab.ui.control.DropDown
        IncidentPolarizationButtonGroup  matlab.ui.container.ButtonGroup
        ParallelButton                  matlab.ui.control.RadioButton
        PerpendicularButton             matlab.ui.control.RadioButton
        IncludeinanalysisCheckBox       matlab.ui.control.CheckBox
        OrientationAngledegreesGaugeLabel  matlab.ui.control.Label
        OrientationAngledegreesGauge    matlab.ui.control.NinetyDegreeGauge
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        PreviousTrajectoryButton        matlab.ui.control.Button
        OutputdirectoryEditFieldLabel   matlab.ui.control.Label
        OutputdirectoryEditField        matlab.ui.control.EditField
        LastchangepointDropDownLabel    matlab.ui.control.Label
        LastchangepointDropDown         matlab.ui.control.DropDown
        ProgressLabel                   matlab.ui.control.Label
        ProgressEditField               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        TrajectoryMatrix    % stores a matrix of intensity traces
        TrajectoryIndex     % identifies the current trajectory
        TrajectoryLength    % length of each trajectory, in frames
        OutputDir           % created by app to store output
        Limit               % last trajectory
        ThetaList           % orientation angles for each trajectory
        ExposureTime        % exposure time of camera (s)
        ChangePoints        % frame index of change points for current trace
        Polarizations       % incident polarization of each region
        IncludeRegion       % bitmap: 1 -> use in analysis, 0 -> ignore
        BufferSize          % number of frames to ignore near change-points
        % look I'd program this better but I'm not doing queues in MATLAB
    end

    methods (Access = private)
    
        function results = PlotCurrentTrace(app, ax)
            l = app.TrajectoryLength;
            xs = linspace(1, l, l);
            ys = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            colr = 'k';
            g_colr = 'b';
            % Plot a different color (black) if data will not be used in analysis
            hold(ax, 'on');
            plot(ax, xs(1:app.BufferSize), ys(1:app.BufferSize), colr, 'LineStyle', ':');
            
            low = app.BufferSize;
            for i = 1:length(app.ChangePoints)
                good_low = app.ChangePoints(i) - app.BufferSize;
                if app.IncludeRegion(i)
                    p_colr = g_colr;
                else
                    p_colr = colr;
                end
                plot(ax, xs(low:good_low), ys(low:good_low), p_colr);
                bad_high = app.ChangePoints(i) + app.BufferSize;
                plot(ax, xs(good_low:bad_high), ys(good_low:bad_high), colr, 'LineStyle', ':');
                low = bad_high;
            end
            plot(ax, xs(low:end), ys(low:end), colr);
            hold(ax, 'off');
        end
        
        function results = PlotChangePoints(app, ax)
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            y=get(ax,'ylim');
            hold(ax, 'on');
            
            % Line drawn where data analysis will start in first region
            plot(ax, [app.BufferSize, app.BufferSize],y,'r');
            
            for i = 1:length(idxs)
                plot(ax, [app.ChangePoints(i), app.ChangePoints(i)],y,'r');
                text(ax, app.ChangePoints(i) - 20, 0, idxs_str(i));
            end
            hold(ax, 'off');
            
            
        end
             
        function results = UpdateSlider(app, idx)
            % Set slider limits
            lower_limit = 0;
            upper_limit = app.TrajectoryLength;
            if idx > 1
                lower_limit = app.ChangePoints(idx-1);
            end
            if idx < length(app.ChangePoints)
                upper_limit = app.ChangePoints(idx+1);
            end
                
            app.LocationSlider.Limits = [lower_limit upper_limit];
            
            % Set the slider value to the current change point
            app.LocationSlider.Value = app.ChangePoints(idx);
        end
        
        function theta = CalculateThetaFromRho(app, rho)
            % From Bohn, et al: The dichroic ratio is defined as the ratio of
            % absorbance of 'transverse electric' to absorbance of 'transverse
            % magnetic'; here, transverse is with respect to the plane of
            % incidence. Therefore, light polarized perpendicular to the z-axis,
            % which has its electric vector in the xy-plane, has its electric vector
            % oriented transverse to the plane of incidence (the xz-plane), and thus
            % A parallel = A transverse electric.
            
            
            % Using the known angle of incidence, calculate the angle the transition
            % moment subtends with the z-axis
            % Adapted from Mark McLean's code
            
            n1=1.515;
            n2=1.335;
            n=n2/n1;
            theta_i = 80;
            E_x = abs(2*sqrt(power(sind(theta_i),2) - power(n,2)) * cosd(theta_i) / ...
                sqrt(1-power(n,2)) / sqrt((1+power(n,2)) * power(sind(theta_i),2) - ...
                power(n,2)));
            E_y = abs(2*cosd(theta_i) / sqrt(1 - power(n,2)));
            E_z = abs(2*cosd(theta_i) * sind(theta_i) / sqrt(1-power(n,2)) / ...
                sqrt((1+power(n,2)) * power(sind(theta_i),2) - power(n,2)));
            
            numerator = (power(E_y, 2) / rho) - power(E_x, 2);
            denom = 2 * power(E_z, 2);
            frac = numerator / denom;
            theta = acotd(sqrt(frac));
        end
        
        function results = IdentifyChangePoints(app)
            %change_points = findcp(app.TrajectoryMatrix(app.TrajectoryIndex,:));
            change_points = [50 100 150 200 250 300 350 400 450];
            app.ChangePoints = change_points;
            
            % Set the default polarization for each region
            app.Polarizations = cell(length(change_points)+1, 1);
            for i = 1:length(app.Polarizations)
               if mod(i, 2) == 1
                    app.Polarizations{i} = 'Perpendicular';
               else
                    app.Polarizations{i} = 'Parallel';
               end
            end
            
            % Initially, include all points
            app.IncludeRegion = ones(length(change_points)+1, 1);
            app.IncludeRegion(length(change_points)+1) = 0; % except the last one


            % Also update UI elements
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            app.SelectedChangePointDropDown.Items = idxs_str;
            app.LastchangepointDropDown.Items = idxs_str(2:end);
            app.LastchangepointDropDown.Value = '9';
            app.SelectedChangePointDropDown.Value = '1';
            UpdateSlider(app, 1);
            app.PerpendicularButton.Value = 1;
            app.ParallelButton.Value = 0;
            app.IncludeinanalysisCheckBox.Value = 1;
        end
        
        function results = TerminateApp(app)
            
            
            % Generate a histogram of the orientation angles
            thetas = app.ThetaList;
            cla(app.UIAxes);
            histogram(app.UIAxes, thetas, 'BinWidth', 1, 'Normalization', 'probability');
            title(app.UIAxes, 'Orientation Histogram')
            xlabel(app.UIAxes, 'Orientation angle (degrees)')
            ylabel(app.UIAxes, 'Probability')
            
            % Save data
            idx_lst = linspace(1, app.Limit, app.Limit);
            matrix = [idx_lst' thetas];
            theta_fname = strcat(app.OutputDir, '/thetas.csv');
            hist_fname = strcat(app.OutputDir, '/hist.tif');
            f = figure;
            ax = axes(f);
            histogram(ax, thetas, 'BinWidth', 1, 'Normalization', 'probability');
            title(ax, 'Orientation Histogram');
            xlabel(ax, 'Orientation angle (degrees)')
            ylabel(ax, 'Probability')
            csvwrite(theta_fname, matrix);
            saveas(ax, hist_fname);
            
        end
        
        function results = CalculateOrientationAngle(app)
            l = length(app.ChangePoints);
            traj = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            perpendicular_intensities = []; % a list of counts at perpendicular ex.
            parallel_intensities = [];
            low = app.BufferSize;
            for i = 1:l
                 high = app.ChangePoints(i) - app.BufferSize;
                 points = traj(low:high);
                 if app.IncludeRegion(i)
                     if strcmp(app.Polarizations{i}, 'Parallel')
                         parallel_intensities = [parallel_intensities points];
                     else
                         perpendicular_intensities = [perpendicular_intensities points];
                     end
                 end
                 low = app.ChangePoints(i) + app.BufferSize;
            end
            
            % use last window?
            %TODO: use last window if desired
            
            % Compute averages and display theta
            I_perp = mean(perpendicular_intensities);
            I_par = mean(parallel_intensities);
            rho = I_perp / I_par;
            theta = CalculateThetaFromRho(app, rho);
            app.ThetaList(app.TrajectoryIndex) = theta;
            app.OrientationAngledegreesGauge.Value = theta;
            app.AngleEditField.Value = theta;
        end
        
        % To be called whenever a new trajectory is loaded
        function results = PlotAndAnalyze(app)
            cla(app.UIAxes); % clean up axes first
            IdentifyChangePoints(app);
            PlotCurrentTrace(app, app.UIAxes);
            
            % Display change points on the current plot
            PlotChangePoints(app, app.UIAxes);
            CalculateOrientationAngle(app);
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadTrajectoriesButton
        function LoadTrajectoriesButtonPushed(app, event)

            % create a name for the output directory
            s = app.TrajectorymatrixEditField.Value;
            tmp = split(s, '/');
            tmp2 = tmp{end};
            tmp3 = split(tmp2, '.');
            dir_name = tmp3{1};
            output_location = app.OutputdirectoryEditField.Value;
            new_dir = strcat(output_location, '/', dir_name);
            mkdir(new_dir);
            app.OutputDir = new_dir;
            
            % load the matrix
            data = load(app.TrajectorymatrixEditField.Value);
            app.TrajectoryMatrix = data.mansel_int_array;
            app.TrajectoryIndex = 1;
            app.TrajectoryLength = length(data.mansel_int_array(1,:));
            app.Limit = length(data.mansel_int_array(:,1));
            app.ThetaList = zeros(app.Limit, 1);
            app.BufferSize = 10;
            
            % Initial display and analysis
            PlotAndAnalyze(app);


        end

        % Value changed function: TrajectorymatrixEditField
        function TrajectorymatrixEditFieldValueChanged(app, event)
            value = app.TrajectorymatrixEditField.Value;
            
        end

        % Button pushed function: DiscardTrajectoryButton
        function DiscardTrajectoryButtonPushed(app, event)
            app.ThetaList(app.TrajectoryIndex) = -1;
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            if app.TrajectoryIndex > app.Limit
               TerminateApp(app); 
            else
                PlotAndAnalyze(app);
            end
            
            
        end

        % Callback function
        function IdentifyChangePointsButtonPushed(app, event)
            IdentifyChangePoints(app);
            CalculateOrientationAngle(app);
        end

        % Value changing function: LocationSlider
        function LocationSliderValueChanging(app, event)
            changingValue = event.Value;
            cp_index_str = app.SelectedChangePointDropDown.Value;
            cp_index = str2num(cp_index_str);
            app.ChangePoints(cp_index) = changingValue;
            
            % Redraw most everything, but don't re-identify change-points
            cla(app.UIAxes);
            PlotCurrentTrace(app, app.UIAxes);
            PlotChangePoints(app, app.UIAxes);
            CalculateOrientationAngle(app);
        end

        % Value changed function: SelectedChangePointDropDown
        function SelectedChangePointDropDownValueChanged(app, event)
            value = app.SelectedChangePointDropDown.Value;
            
            % Set the slider limit to the values of the surrounding
            % change points, or the end of the trajectory
            x = str2num(value);
            UpdateSlider(app, x);
            % Update the radio button and check box for inclusion
            if strcmp(app.Polarizations{x}, 'Parallel')
                app.ParallelButton.Value = 1;
                app.PerpendicularButton.Value = 0;
            else
                app.ParallelButton.Value = 0;
                app.PerpendicularButton.Value = 1;
            end
            
            app.IncludeinanalysisCheckBox.Value = app.IncludeRegion(x);
        end

        % Selection changed function: IncidentPolarizationButtonGroup
        function IncidentPolarizationButtonGroupSelectionChanged(app, event)
            selectedButton = app.IncidentPolarizationButtonGroup.SelectedObject;
            
            % Get the relevant change-point and thus the region to which this applies
            change_point = str2num(app.SelectedChangePointDropDown.Value);
            app.Polarizations{change_point} = selectedButton.Text;
            
            % Now we need to recalculate
            CalculateOrientationAngle(app);
        end

        % Value changed function: IncludeinanalysisCheckBox
        function IncludeinanalysisCheckBoxValueChanged(app, event)
            value = app.IncludeinanalysisCheckBox.Value;
            region = str2num(app.SelectedChangePointDropDown.Value);
            app.IncludeRegion(region) = value;
            
            % Plot again
            cla(app.UIAxes);
            PlotCurrentTrace(app, app.UIAxes);
            PlotChangePoints(app, app.UIAxes);
            
            % Recalculate orientation angle
            CalculateOrientationAngle(app);
        end

        % Callback function
        function CalculateOrientationAngleButtonPushed(app, event)
            CalculateOrientationAngle(app);
        end

        % Button pushed function: SaveandProceedButton
        function SaveandProceedButtonPushed(app, event)
            
            % Save the current trajectory
            f = figure;
            ax = axes(f);
            PlotCurrentTrace(app, ax);
            PlotChangePoints(app, ax);
            CalculateOrientationAngle(app);
            txt = sprintf('\\theta=%2.1f', app.ThetaList(app.TrajectoryIndex));
            y=get(ax,'ylim');
            text(ax, 400, y(2)*0.8, txt);
            
            plot_fname = strcat(app.OutputDir, '/', sprintf('Trajectory%03d.tif', app.TrajectoryIndex)); 
            saveas(ax, plot_fname);
            delete(f);
            
            % Load next trajectory or close if done
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            app.ProgressEditField.Value = (app.TrajectoryIndex / app.Limit) * 100;
            if app.TrajectoryIndex > app.Limit
               TerminateApp(app); 
            else
                PlotAndAnalyze(app);
            end
            
            
        end

        % Button pushed function: PreviousTrajectoryButton
        function PreviousTrajectoryButtonPushed(app, event)
            if app.TrajectoryIndex == 1
                return
            else
                app.TrajectoryIndex = app.TrajectoryIndex - 1;
                PlotAndAnalyze(app);
            end
        end

        % Value changed function: LastchangepointDropDown
        function LastchangepointDropDownValueChanged(app, event)
            value = app.LastchangepointDropDown.Value;
            v = str2num(value);
            for i=1:v+1
               app.IncludeRegion(i) = 1; 
            end
            for i=v+1:app.Limit
                app.IncludeRegion(i) = 0;
            end
            PlotCurrentTrace(app, app.UIAxes);
            PlotChangePoints(app, app.UIAxes);
            CalculateOrientationAngle(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity Trace')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Fluorescence Intensity (a.u.)')
            app.TrajectorymatrixEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectorymatrixEditFieldLabel.Text = 'Trajectory matrix';
            app.TrajectorymatrixEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectorymatrixEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorymatrixEditFieldValueChanged, true);
            app.LoadTrajectoriesButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoriesButtonPushed, true);
            app.LoadTrajectoriesButton.Text = 'Load Trajectories';
            app.DiscardTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DiscardTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardTrajectoryButtonPushed, true);
            app.DiscardTrajectoryButton.Text = 'Discard Trajectory';
            app.SaveandProceedButton = uibutton(app.UIFigure, 'push');
            app.SaveandProceedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandProceedButtonPushed, true);
            app.SaveandProceedButton.Text = 'Save and Proceed';
            app.LocationSliderLabel = uilabel(app.UIFigure);
            app.LocationSliderLabel.Text = 'Location';
            app.LocationSlider = uislider(app.UIFigure);
            app.LocationSlider.ValueChangingFcn = createCallbackFcn(app, @LocationSliderValueChanging, true);
            app.SelectedChangePointDropDownLabel = uilabel(app.UIFigure);
            app.SelectedChangePointDropDownLabel.Text = 'Selected Change Point';
            app.SelectedChangePointDropDown = uidropdown(app.UIFigure);
            app.SelectedChangePointDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChangePointDropDownValueChanged, true);
            app.IncidentPolarizationButtonGroup = uibuttongroup(app.UIFigure);
            app.IncidentPolarizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IncidentPolarizationButtonGroupSelectionChanged, true);
            app.IncidentPolarizationButtonGroup.Title = 'Incident Polarization';
            app.ParallelButton = uiradiobutton(app.IncidentPolarizationButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.PerpendicularButton = uiradiobutton(app.IncidentPolarizationButtonGroup);
            app.PerpendicularButton.Text = 'Perpendicular';
            app.IncludeinanalysisCheckBox = uicheckbox(app.UIFigure);
            app.IncludeinanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeinanalysisCheckBoxValueChanged, true);
            app.IncludeinanalysisCheckBox.Text = 'Include in analysis';
            app.OrientationAngledegreesGaugeLabel = uilabel(app.UIFigure);
            app.OrientationAngledegreesGaugeLabel.Text = 'Orientation Angle (degrees)';
            app.OrientationAngledegreesGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.OrientationAngledegreesGauge.Limits = [0 90];
            app.OrientationAngledegreesGauge.Value = 45;
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Editable = 'off';
            app.PreviousTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.PreviousTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrajectoryButtonPushed, true);
            app.PreviousTrajectoryButton.Text = 'Previous Trajectory';
            app.OutputdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.OutputdirectoryEditFieldLabel.Text = 'Output directory';
            app.OutputdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.OutputdirectoryEditField.Value = '~/';
            app.LastchangepointDropDownLabel = uilabel(app.UIFigure);
            app.LastchangepointDropDownLabel.Text = 'Last change point';
            app.LastchangepointDropDown = uidropdown(app.UIFigure);
            app.LastchangepointDropDown.ValueChangedFcn = createCallbackFcn(app, @LastchangepointDropDownValueChanged, true);
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress (%)';
            app.ProgressEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analyze_smLD_Handy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end