classdef Analyze_smLD_Spinny < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TrajectorymatrixEditFieldLabel  matlab.ui.control.Label
        TrajectorymatrixEditField       matlab.ui.control.EditField
        LoadTrajectoriesButton          matlab.ui.control.Button
        DiscardTrajectoryButton         matlab.ui.control.Button
        SaveandProceedButton            matlab.ui.control.Button
        LocationSliderLabel             matlab.ui.control.Label
        LocationSlider                  matlab.ui.control.Slider
        SelectedChangePointDropDownLabel  matlab.ui.control.Label
        SelectedChangePointDropDown     matlab.ui.control.DropDown
        OrientationAngledegreesGaugeLabel  matlab.ui.control.Label
        OrientationAngledegreesGauge    matlab.ui.control.NinetyDegreeGauge
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        PreviousTrajectoryButton        matlab.ui.control.Button
        OutputdirectoryEditFieldLabel   matlab.ui.control.Label
        OutputdirectoryEditField        matlab.ui.control.EditField
        ProgressLabel                   matlab.ui.control.Label
        ProgressEditField               matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        MotorFrequencyEditFieldLabel    matlab.ui.control.Label
        MotorFrequencyEditField         matlab.ui.control.NumericEditField
        MaxPerpendicularCheckBox        matlab.ui.control.CheckBox
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        ExposureTimeEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        TrajectoryMatrix    % stores a matrix of intensity traces
        TrajectoryIndex     % identifies the current trajectory
        TrajectoryLength    % length of each trajectory, in frames
        OutputDir           % created by app to store output
        Limit               % last trajectory
        ThetaList           % orientation angles for each trajectory
        ExposureTime        % exposure time of camera (s)
        ChangePoints        % frame index of change points for current trace
        PerpendicularPeak   % set to 1 if maximum peak is perpendicular
        Frequencies         % x-axis of FFT
        Amplitudes          % y-axis of FFT
    end

    methods (Access = private)
    
        function results = PlotCurrentTrace(app, ax)
            l = app.TrajectoryLength;
            xs = linspace(1, l, l);
            ys = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            if (isempty(app.ChangePoints))
                plot(ax, xs, ys, 'r');
            else
                plot(ax, xs(1:app.ChangePoints(1)), ys(1:app.ChangePoints(1)), 'r', 'LineStyle', '-');
                hold(ax, 'on');
                plot(ax, xs(app.ChangePoints(1):end), ys(app.ChangePoints(1):end), 'k', 'LineStyle', ':');
                hold(ax, 'off');    
            end
        end
        
        function results = PlotChangePoints(app, ax)
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            y=get(ax,'ylim');
            hold(ax, 'on');     
            for i = 1:length(idxs)
                plot(ax, [app.ChangePoints(i), app.ChangePoints(i)],y,'r');
                text(ax, app.ChangePoints(i) - 20, 0, idxs_str(i));
            end
            hold(ax, 'off');
            
            
        end


        function theta = CalculateThetaFromRho(app, rho)
            % From Bohn, et al: The dichroic ratio is defined as the ratio of
            % absorbance of 'transverse electric' to absorbance of 'transverse
            % magnetic'; here, transverse is with respect to the plane of
            % incidence. Therefore, light polarized perpendicular to the z-axis,
            % which has its electric vector in the xy-plane, has its electric vector
            % oriented transverse to the plane of incidence (the xz-plane), and thus
            % A parallel = A transverse electric.
            
            
            % Using the known angle of incidence, calculate the angle the transition
            % moment subtends with the z-axis
            % Adapted from Mark McLean's code
            
            n1=1.515;
            n2=1.335;
            n=n2/n1;
            theta_i = 80;
            E_x = abs(2*sqrt(power(sind(theta_i),2) - power(n,2)) * cosd(theta_i) / ...
                sqrt(1-power(n,2)) / sqrt((1+power(n,2)) * power(sind(theta_i),2) - ...
                power(n,2)));
            E_y = abs(2*cosd(theta_i) / sqrt(1 - power(n,2)));
            E_z = abs(2*cosd(theta_i) * sind(theta_i) / sqrt(1-power(n,2)) / ...
                sqrt((1+power(n,2)) * power(sind(theta_i),2) - power(n,2)));
            
            numerator = (power(E_y, 2) / rho) - power(E_x, 2);
            denom = 2 * power(E_z, 2);
            frac = numerator / denom;
            theta = acotd(sqrt(frac));
        end
        
        function results = IdentifyChangePoints(app)
            change_points = findcp(app.TrajectoryMatrix(app.TrajectoryIndex,:));
            app.ChangePoints = change_points;
 
            % Also update UI elements
            idxs = linspace(1, length(app.ChangePoints), length(app.ChangePoints));
            idxs_str = split(num2str(idxs));
            %UpdateSlider(app, 1);
        end
        
        function results = TerminateApp(app)
            
            
            % Generate a histogram of the orientation angles
            thetas = app.ThetaList;
            cla(app.UIAxes);
            histogram(app.UIAxes, thetas, 'BinWidth', 1, 'Normalization', 'probability');
            title(app.UIAxes, 'Orientation Histogram')
            xlabel(app.UIAxes, 'Orientation angle (degrees)')
            ylabel(app.UIAxes, 'Probability')
            
            % Save data
            idx_lst = linspace(1, app.Limit, app.Limit);
            matrix = [idx_lst' thetas];
            theta_fname = strcat(app.OutputDir, '/thetas.csv');
            hist_fname = strcat(app.OutputDir, '/hist.tif');
            f = figure;
            ax = axes(f);
            histogram(ax, thetas, 'BinWidth', 1, 'Normalization', 'probability');
            title(ax, 'Orientation Histogram');
            xlabel(ax, 'Orientation angle (degrees)')
            ylabel(ax, 'Probability')
            csvwrite(theta_fname, matrix);
            saveas(ax, hist_fname);
            
        end
        
        function results = CalculateOrientationAngle(app)
            % Get max amplitude from Fourier spectrum
            [peak, index] = max(app.Amplitudes(2:end));
            if (~isempty(app.ChangePoints))
                avg = mean(app.TrajectoryMatrix(app.TrajectoryIndex,1:app.ChangePoints(1)));
            else
                avg = mean(app.TrajectoryMatrix(app.TrajectoryIndex,:));
            end
            perpendicular_intensity = avg + (0.5 * peak);
            parallel_intensity = avg - (0.5 * peak);
            
            
            % Compute averages and display theta
            I_perp = perpendicular_intensity;
            I_par = parallel_intensity;
            rho = I_perp / I_par;
            theta = CalculateThetaFromRho(app, rho);
            if ~(isreal(theta))
                app.ThetaList(app.TrajectoryIndex) = -1;
                app.OrientationAngledegreesGauge.Value = 90;
                app.AngleEditField.Value = -1;
            
            else
                app.ThetaList(app.TrajectoryIndex) = theta;
                app.OrientationAngledegreesGauge.Value = theta;
                app.AngleEditField.Value = theta;
            end
        end
        
        % To be called whenever a new trajectory is loaded
        function results = PlotAndAnalyze(app)
            cla(app.UIAxes); % clean up axes first
            IdentifyChangePoints(app);
            PlotCurrentTrace(app, app.UIAxes);
            PlotChangePoints(app, app.UIAxes);
            ComputeFourierTransform(app);
            PlotOneSidedSpectrum(app, app.UIAxes2);
            CalculateOrientationAngle(app);
        end
        
        function results = ComputeFourierTransform(app)
            % Get time domain data up to the first change point (assume it is the only bleach, and it bleaches completely)
            if (~isempty(app.ChangePoints))
                data = app.TrajectoryMatrix(app.TrajectoryIndex,1:app.ChangePoints(1));
            else
                data = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            end
            
            % Compute Fourier Transform and one-sided spectrum
            Y = fft(data);
            L = length(data); % number of samples
            Fs = 1 / app.ExposureTime; % sampling frequency (Hz)
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            app.Frequencies = f;
            app.Amplitudes = P1;
        end
        
        function results = PlotOneSidedSpectrum(app, ax)
            cla(ax);
            plot(ax, app.Frequencies(2:end), app.Amplitudes(2:end)); % exclude 0 Hz (constant offset)
            hold(ax, 'on');
            [peak, index] = max(app.Amplitudes(2:end));
            real_index = index + 1; % because we started at 2
            y=get(ax,'ylim');
            plot(ax, [app.Frequencies(real_index), app.Frequencies(real_index)],y,'r');
            hold(ax, 'off');
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadTrajectoriesButton
        function LoadTrajectoriesButtonPushed(app, event)

            % create a name for the output directory
            s = app.TrajectorymatrixEditField.Value;
            tmp = split(s, '/');
            tmp2 = tmp{end};
            tmp3 = split(tmp2, '.');
            dir_name = tmp3{1};
            output_location = app.OutputdirectoryEditField.Value;
            new_dir = strcat(output_location, '/', dir_name);
            mkdir(new_dir);
            app.OutputDir = new_dir;
            
            % load the matrix
            data = load(app.TrajectorymatrixEditField.Value);
            app.TrajectoryMatrix = data.mansel_int_array;
            app.TrajectoryIndex = 1;
            app.TrajectoryLength = length(data.mansel_int_array(1,:));
            app.Limit = length(data.mansel_int_array(:,1));
            app.ThetaList = zeros(app.Limit, 1);
            app.ExposureTime = app.ExposureTimeEditField.Value;
            % Initial display and analysis
            PlotAndAnalyze(app);


        end

        % Value changed function: TrajectorymatrixEditField
        function TrajectorymatrixEditFieldValueChanged(app, event)
            value = app.TrajectorymatrixEditField.Value;
            
        end

        % Button pushed function: DiscardTrajectoryButton
        function DiscardTrajectoryButtonPushed(app, event)
            app.ThetaList(app.TrajectoryIndex) = -1;
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            if app.TrajectoryIndex > app.Limit
               TerminateApp(app); 
            else
                PlotAndAnalyze(app);
            end
            
            
        end

        % Callback function
        function IdentifyChangePointsButtonPushed(app, event)
            IdentifyChangePoints(app);
            CalculateOrientationAngle(app);
        end

        % Value changing function: LocationSlider
        function LocationSliderValueChanging(app, event)
           %{
            changingValue = event.Value;
            cp_index_str = app.SelectedChangePointDropDown.Value;
            cp_index = str2num(cp_index_str);
            app.ChangePoints(cp_index) = changingValue;
            
            % Redraw most everything, but don't re-identify change-points
            cla(app.UIAxes);
            PlotCurrentTrace(app, app.UIAxes);
            PlotChangePoints(app, app.UIAxes);
            CalculateOrientationAngle(app);
            %}
        end

        % Value changed function: SelectedChangePointDropDown
        function SelectedChangePointDropDownValueChanged(app, event)
           %{
            value = app.SelectedChangePointDropDown.Value;
            
            % Set the slider limit to the values of the surrounding
            % change points, or the end of the trajectory
            x = str2num(value);
            UpdateSlider(app, x);
            %}
        end

        % Callback function
        function CalculateOrientationAngleButtonPushed(app, event)
            CalculateOrientationAngle(app);
        end

        % Button pushed function: SaveandProceedButton
        function SaveandProceedButtonPushed(app, event)
            
            % Save the current trajectory
            f = figure;
            ax = axes(f);
            PlotCurrentTrace(app, ax);
            PlotChangePoints(app, ax);
            CalculateOrientationAngle(app);
            txt = sprintf('\\theta=%2.1f', app.ThetaList(app.TrajectoryIndex));
            y=get(ax,'ylim');
            text(ax, 400, y(2)*0.8, txt);
            
            plot_fname = strcat(app.OutputDir, '/', sprintf('Trajectory%03d-td.tif', app.TrajectoryIndex)); 
            saveas(ax, plot_fname);
            delete(f);
            
            % Save the Fourier spectrum
            f = figure;
            ax = axes(f);
            PlotOneSidedSpectrum(app, ax);           
            plot_fname = strcat(app.OutputDir, '/', sprintf('Trajectory%03d-fd.tif', app.TrajectoryIndex));
            saveas(ax, plot_fname);
            delete(f);
            
            % Load next trajectory or close if done
            app.TrajectoryIndex = app.TrajectoryIndex + 1;
            app.ProgressEditField.Value = (app.TrajectoryIndex / app.Limit) * 100;
            if app.TrajectoryIndex > app.Limit
               TerminateApp(app); 
            else
                PlotAndAnalyze(app);
            end
            
            
        end

        % Button pushed function: PreviousTrajectoryButton
        function PreviousTrajectoryButtonPushed(app, event)
            if app.TrajectoryIndex == 1
                return
            else
                app.TrajectoryIndex = app.TrajectoryIndex - 1;
                PlotAndAnalyze(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity Trace')
            xlabel(app.UIAxes, 'frame')
            ylabel(app.UIAxes, 'Fluorescence Intensity (a.u.)')
            app.TrajectorymatrixEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectorymatrixEditFieldLabel.Text = 'Trajectory matrix';
            app.TrajectorymatrixEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectorymatrixEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorymatrixEditFieldValueChanged, true);
            app.LoadTrajectoriesButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoriesButtonPushed, true);
            app.LoadTrajectoriesButton.Text = 'Load Trajectories';
            app.DiscardTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DiscardTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardTrajectoryButtonPushed, true);
            app.DiscardTrajectoryButton.Text = 'Discard Trajectory';
            app.SaveandProceedButton = uibutton(app.UIFigure, 'push');
            app.SaveandProceedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandProceedButtonPushed, true);
            app.SaveandProceedButton.Text = 'Save and Proceed';
            app.LocationSliderLabel = uilabel(app.UIFigure);
            app.LocationSliderLabel.Text = 'Location';
            app.LocationSlider = uislider(app.UIFigure);
            app.LocationSlider.ValueChangingFcn = createCallbackFcn(app, @LocationSliderValueChanging, true);
            app.SelectedChangePointDropDownLabel = uilabel(app.UIFigure);
            app.SelectedChangePointDropDownLabel.Text = 'Selected Change Point';
            app.SelectedChangePointDropDown = uidropdown(app.UIFigure);
            app.SelectedChangePointDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedChangePointDropDownValueChanged, true);
            app.OrientationAngledegreesGaugeLabel = uilabel(app.UIFigure);
            app.OrientationAngledegreesGaugeLabel.Text = 'Orientation Angle (degrees)';
            app.OrientationAngledegreesGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.OrientationAngledegreesGauge.Limits = [0 90];
            app.OrientationAngledegreesGauge.Value = 45;
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Editable = 'off';
            app.PreviousTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.PreviousTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrajectoryButtonPushed, true);
            app.PreviousTrajectoryButton.Text = 'Previous Trajectory';
            app.OutputdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.OutputdirectoryEditFieldLabel.Text = 'Output directory';
            app.OutputdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.OutputdirectoryEditField.Value = '~/';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress (%)';
            app.ProgressEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'One-Sided Spectrum')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Amplitude (a.u.)')
            app.MotorFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.MotorFrequencyEditFieldLabel.Text = 'Motor Frequency';
            app.MotorFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorFrequencyEditField.Limits = [0 4];
            app.MotorFrequencyEditField.Value = 1;
            app.MaxPerpendicularCheckBox = uicheckbox(app.UIFigure);
            app.MaxPerpendicularCheckBox.Text = 'Max = Perpendicular';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureTimeEditField.Value = 0.05;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analyze_smLD_Spinny

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end