classdef Triage_smLD_Handy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TrajectoryMatrixEditFieldLabel  matlab.ui.control.Label
        TrajectoryMatrixEditField       matlab.ui.control.EditField
        LoadandTriageButton             matlab.ui.control.Button
        CurrentTrajectorySpinnerLabel   matlab.ui.control.Label
        CurrentTrajectorySpinner        matlab.ui.control.Spinner
        ClassSpinnerLabel               matlab.ui.control.Label
        ClassSpinner                    matlab.ui.control.Spinner
    end


    properties (Access = private)
        TrajectoryMatrix        % store the trajectories here
        TrajectoryLength        % length of each trajectory in frames
        TrajectoryIndex         % current trajectory index
        TrajectoryLimit         % number of trajectories
        ClassArray             % 1: good, 2: ok, 3: bad
    end

    methods (Access = private)
    
        function results = Triage(app)
            len = length(app.TrajectoryMatrix(:,1));
            for i = 1:len
                Classify(app, app.TrajectoryMatrix(i,:), i);
            end
        end
        
        function results = Classify(app, traj, index)
            change_points = findcp(traj);
            if length(change_points) < 2
                app.ClassArray(index) = 3;
            elseif length(change_points) < 3
                app.ClassArray(index) = 2;
            else
                app.ClassArray(index) = 1;
             
            end
        end
        
        function results = PlotAndUpdate(app)
            xs = linspace(1, app.TrajectoryLength, app.TrajectoryLength);
            ys = app.TrajectoryMatrix(app.TrajectoryIndex,:);
            plot(app.UIAxes, xs, ys);
            app.ClassSpinner.Value = app.ClassArray(app.TrajectoryIndex);
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadandTriageButton
        function LoadandTriageButtonPushed(app, event)
            obj = load(app.TrajectoryMatrixEditField.Value);
            app.TrajectoryMatrix = obj.mansel_int_array;
            app.TrajectoryIndex = 1;
            app.TrajectoryLength = length(app.TrajectoryMatrix(1,:));
            app.TrajectoryLimit = length(app.TrajectoryMatrix(:,1));
            app.CurrentTrajectorySpinner.Limits = [1 app.TrajectoryLimit];
            app.ClassArray = ones(app.TrajectoryLimit, 1);
            
            % Run the classifier and display first trajectory
            Triage(app);
            PlotAndUpdate(app);
        end

        % Value changing function: CurrentTrajectorySpinner
        function CurrentTrajectorySpinnerValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > app.TrajectoryLimit
                return
            else
                app.TrajectoryIndex = changingValue;
                PlotAndUpdate(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current Trace')
            xlabel(app.UIAxes, 'frame')
            ylabel(app.UIAxes, 'Fluorescence Intensity (a.u.)')
            app.TrajectoryMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectoryMatrixEditFieldLabel.Text = 'Trajectory Matrix';
            app.TrajectoryMatrixEditField = uieditfield(app.UIFigure, 'text');
            app.LoadandTriageButton = uibutton(app.UIFigure, 'push');
            app.LoadandTriageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandTriageButtonPushed, true);
            app.LoadandTriageButton.Text = 'Load and Triage';
            app.CurrentTrajectorySpinnerLabel = uilabel(app.UIFigure);
            app.CurrentTrajectorySpinnerLabel.Text = 'Current Trajectory';
            app.CurrentTrajectorySpinner = uispinner(app.UIFigure);
            app.CurrentTrajectorySpinner.ValueChangingFcn = createCallbackFcn(app, @CurrentTrajectorySpinnerValueChanging, true);
            app.CurrentTrajectorySpinner.Limits = [0 Inf];
            app.ClassSpinnerLabel = uilabel(app.UIFigure);
            app.ClassSpinnerLabel.Text = 'Class';
            app.ClassSpinner = uispinner(app.UIFigure);
            app.ClassSpinner.Limits = [1 3];
            app.ClassSpinner.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Triage_smLD_Handy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end